classdef TestingForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FormTest                        matlab.ui.Figure
        ImageTestPanel                  matlab.ui.container.Panel
        UIAxesOri                       matlab.ui.control.UIAxes
        UIAxesFaceCrop                  matlab.ui.control.UIAxes
        UIAxesResult                    matlab.ui.control.UIAxes
        DetailModelPanel                matlab.ui.container.Panel
        ConfusionMatrixPanel            matlab.ui.container.Panel
        AkurasiLabel                    matlab.ui.control.Label
        SensitivityLabel                matlab.ui.control.Label
        SpecificityLabel                matlab.ui.control.Label
        PrecisionLabel                  matlab.ui.control.Label
        F1_scoreLabel                   matlab.ui.control.Label
        LabelAkurasi                    matlab.ui.control.Label
        Label_Sensitif                  matlab.ui.control.Label
        Label_Spec                      matlab.ui.control.Label
        Label_Prec                      matlab.ui.control.Label
        Label_F1                        matlab.ui.control.Label
        ProsesButton                    matlab.ui.control.Button
        SettingImageTestPanel           matlab.ui.container.Panel
        PilihButton                     matlab.ui.control.Button
        range5005000Label               matlab.ui.control.Label
        LokasiFolderEditFieldLabel      matlab.ui.control.Label
        LokasiFolderEditField           matlab.ui.control.EditField
        ResizeImageEditFieldLabel       matlab.ui.control.Label
        ResizeImageEditField            matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        ModelDropDownLabel              matlab.ui.control.Label
        ModelDropDown                   matlab.ui.control.DropDown
        StatusBarPanel                  matlab.ui.container.Panel
        StatusLabel                     matlab.ui.control.Label
        DetailResultPanel               matlab.ui.container.Panel
        NamaLabel                       matlab.ui.control.Label
        LabelNamaFile                   matlab.ui.control.Label
        UkuranLabel                     matlab.ui.control.Label
        LabelUkuran                     matlab.ui.control.Label
        WaktuProsesLabel                matlab.ui.control.Label
        LabelExcTime                    matlab.ui.control.Label
        NilaiEuclideanLabel             matlab.ui.control.Label
        LabelMinEuc                     matlab.ui.control.Label
        KelasLabel                      matlab.ui.control.Label
        LabelKelas                      matlab.ui.control.Label
        HasilKlasifikasiEuclideanPanel  matlab.ui.container.Panel
        UITableEuclidean                matlab.ui.control.Table
    end

    
    properties (Access = public)
        ModelPCA 
        FaceDetector
        DRMFModel 
        imgFace
    end
    
    methods (Access = public)
        
        function fillModelBox(app)
            app.StatusLabel.Text = sprintf("Collecting data model %s",app.ModelDropDown.Value); pause(0.00000000000000001);
            load(fullfile('model', app.ModelDropDown.Value));   
            
            fprintf("To see all detail variable from model, double click file in folder model/%s \n", app.ModelDropDown.Value);        
            
            app.ModelPCA     = model; 
            app.ResizeImageEditField.Value = app.ModelPCA.resize;
            app.ThresholdEditField.Value = app.ModelPCA.thresholdPCA;
            
            ResultAcc = app.ModelPCA.classification;
            
            app.LabelAkurasi.Text = sprintf("%d%%",(ResultAcc.Accuracy*100));
            app.Label_Sensitif.Text = sprintf("%d%%",(ResultAcc.Sensitivity *100));
            app.Label_Spec.Text = sprintf("%d%%",(ResultAcc.Specificity *100));
            app.Label_Prec.Text = sprintf("%d%%",(ResultAcc.Precision *100));
            app.Label_F1.Text = sprintf("%d%%",(ResultAcc.F1_score *100)); 
            
            confusionchart(app.ConfusionMatrixPanel,ResultAcc.confusionmat); 
            
            app.StatusLabel.Text = sprintf("All data model is loaded"); pause(0.00000000000000001);
        end
        
        function LoadModel(app)
            
            app.StatusLabel.Text = sprintf("Selecting model file"); pause(0.00000000000000001);
            file = dir(fullfile(pwd,'model', '*.mat'));
             
            app.ModelDropDown.Items = {file.name}'; 
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('class\'));
            clc; 
            movegui(app.FormTest,'center');
            
            fitting_model     ='models/Chehra_f1.0.mat'; % Load Models
            app.DRMFModel     = load(fitting_model);  
            
            app.FaceDetector  = vision.CascadeObjectDetector(); % Face Detector
            app.FaceDetector.MinSize = [60 60];
            app.FaceDetector.MergeThreshold = 3;
            
            LoadModel(app);
            fillModelBox(app);
        end

        % Button pushed function: PilihButton
        function PilihButtonPushed(app, event)
            [file,path] = uigetfile(fullfile(pwd,'dataset/Test/','*.jpeg'));
            
            if isequal(file,0)
                msgbox("Lokasi kosong, harap pilih kembali!",'modal');
                [file,path] = uigetfile(fullfile(pwd,'dataset/Test/','*.jpeg'));
            else
               app.LokasiFolderEditField.Value = fullfile(path,file);
            end
            
            fprintf("Image for Test : %s\n", file);
            
            app.StatusLabel.Text = sprintf("Process load image to system"); pause(0.00000000000000001);
            img = imread(app.LokasiFolderEditField.Value);
            imshow(img,'Parent',app.UIAxesOri);  
            
            app.StatusLabel.Text = sprintf("Getting facial image from image test"); pause(0.00000000000000001);
            app.imgFace = FaceDetection(img,app.FaceDetector, app.DRMFModel,app.ResizeImageEditField.Value);
            imshow(app.imgFace,'Parent',app.UIAxesFaceCrop);  
            
            app.LabelUkuran.Text = sprintf("%d x %d", size(img,1), size(img,2));
            
            app.StatusLabel.Text = sprintf("Facial data has been set"); pause(0.00000000000000001);
        end

        % Close request function: FormTest
        function FormTestCloseRequest(app, event)
            delete(app) 
        end

        % Button pushed function: ProsesButton
        function ProsesButtonPushed(app, event)
            tic;
            pca = app.ModelPCA.PCA;
            
            app.StatusLabel.Text = sprintf("Prepare to calculating PCA data from model"); pause(0.00000000000000001);
            
            [irow, icol] = size(app.imgFace);
            InImage = reshape(app.imgFace',irow*icol,1); 
            Difference = double(InImage)- pca.mean(:,1); 
            Projected_TestImage = pca.eigenface'*Difference;
            
            fprintf("Convert 1D matrix : (See var InImage in workspace)\n");
            assignin('base','InImage', InImage);
            
            fprintf("Difference InImage with PCA Mean : (See var Difference in workspace)\n");
            assignin('base','Difference', Difference);
            
            fprintf("Projected Test Image : (See var Projected_TestImage in workspace)\n");
            assignin('base','Projected_TestImage', Projected_TestImage);
            
            totalData =  str2double(app.ModelPCA.sumOfData);
            Euc_dist = zeros(1,totalData) ;
            Euc_distDet = cell(totalData,3);
            
            for i = 1 :totalData
                q = app.ModelPCA.ProjectedImages(:,i);
                temp = ( norm( Projected_TestImage - q ) )^2;
                Euc_dist(:,i) = temp;
                 
                Euc_distDet{i,1} = num2str(i);
                Euc_distDet{i,2} = app.ModelPCA.datatrain{i,4};
                Euc_distDet{i,3} = Euc_dist(:,i); 
                
                app.StatusLabel.Text = sprintf("Process calculating [%d]", i); pause(0.00000000000000001);
            end 
            
            fprintf("Each of result euclidian distance : (See table Hasil Klasifikasi Euclidean in Form)\n");
             
            app.UITableEuclidean.Data = Euc_distDet;
            
            app.StatusLabel.Text = sprintf("Getting minimum value of classification"); pause(0.00000000000000001);
            [Euc_dist_min , Recognized_index] = min(Euc_dist);
            
            app.LabelMinEuc.Text = sprintf("%d",Euc_dist_min);
             
            app.LabelNamaFile.Text = app.ModelPCA.datatrain{Recognized_index,2};
            app.LabelKelas.Text = app.ModelPCA.datatrain{Recognized_index,5};
            
            app.StatusLabel.Text = sprintf("Show image result"); pause(0.00000000000000001);
            imgRes = fullfile(app.ModelPCA.datatrain{Recognized_index,3});
            
            imshow(imgRes, 'Parent', app.UIAxesResult);
             
            app.LabelExcTime.Text = sprintf("%f ms",toc); 
            
            app.StatusLabel.Text = sprintf("All process is done with excecution time :%f", toc); pause(0.00000000000000001);
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            value = app.ModelDropDown.Value;
            
            fillModelBox(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FormTest = uifigure;
            app.FormTest.Name = 'Form Test';
            app.FormTest.CloseRequestFcn = createCallbackFcn(app, @FormTestCloseRequest, true);
            app.ImageTestPanel = uipanel(app.FormTest);
            app.ImageTestPanel.Title = 'Image Test';
            app.UIAxesOri = uiaxes(app.ImageTestPanel);
            title(app.UIAxesOri, 'Image Original')
            app.UIAxesFaceCrop = uiaxes(app.ImageTestPanel);
            title(app.UIAxesFaceCrop, 'Facial Crop')
            app.UIAxesResult = uiaxes(app.ImageTestPanel);
            title(app.UIAxesResult, 'Image Hasil Pengenalan')
            app.DetailModelPanel = uipanel(app.FormTest);
            app.DetailModelPanel.Title = 'Detail Model';
            app.ConfusionMatrixPanel = uipanel(app.DetailModelPanel);
            app.ConfusionMatrixPanel.Title = 'Confusion Matrix';
            app.AkurasiLabel = uilabel(app.DetailModelPanel);
            app.AkurasiLabel.Text = 'Akurasi        :';
            app.SensitivityLabel = uilabel(app.DetailModelPanel);
            app.SensitivityLabel.Text = 'Sensitivity   :';
            app.SpecificityLabel = uilabel(app.DetailModelPanel);
            app.SpecificityLabel.Text = 'Specificity   :';
            app.PrecisionLabel = uilabel(app.DetailModelPanel);
            app.PrecisionLabel.Text = 'Precision     : ';
            app.F1_scoreLabel = uilabel(app.DetailModelPanel);
            app.F1_scoreLabel.Text = 'F1_score     : ';
            app.LabelAkurasi = uilabel(app.DetailModelPanel);
            app.LabelAkurasi.Text = '0';
            app.Label_Sensitif = uilabel(app.DetailModelPanel);
            app.Label_Sensitif.Text = '0';
            app.Label_Spec = uilabel(app.DetailModelPanel);
            app.Label_Spec.Text = '0';
            app.Label_Prec = uilabel(app.DetailModelPanel);
            app.Label_Prec.Text = '0';
            app.Label_F1 = uilabel(app.DetailModelPanel);
            app.Label_F1.Text = '0';
            app.ProsesButton = uibutton(app.DetailModelPanel, 'push');
            app.ProsesButton.ButtonPushedFcn = createCallbackFcn(app, @ProsesButtonPushed, true);
            app.ProsesButton.Text = 'Proses';
            app.SettingImageTestPanel = uipanel(app.FormTest);
            app.SettingImageTestPanel.Title = 'Setting Image Test';
            app.PilihButton = uibutton(app.SettingImageTestPanel, 'push');
            app.PilihButton.ButtonPushedFcn = createCallbackFcn(app, @PilihButtonPushed, true);
            app.PilihButton.Text = 'Pilih';
            app.range5005000Label = uilabel(app.SettingImageTestPanel);
            app.range5005000Label.Text = 'range : 500 - 5000';
            app.LokasiFolderEditFieldLabel = uilabel(app.SettingImageTestPanel);
            app.LokasiFolderEditFieldLabel.Text = {'Lokasi'; 'Folder'};
            app.LokasiFolderEditField = uieditfield(app.SettingImageTestPanel, 'text');
            app.LokasiFolderEditField.Editable = 'off';
            app.ResizeImageEditFieldLabel = uilabel(app.SettingImageTestPanel);
            app.ResizeImageEditFieldLabel.Text = {'Resize '; 'Image'};
            app.ResizeImageEditField = uieditfield(app.SettingImageTestPanel, 'numeric');
            app.ResizeImageEditField.Editable = 'off';
            app.ThresholdEditFieldLabel = uilabel(app.SettingImageTestPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.SettingImageTestPanel, 'numeric');
            app.ThresholdEditField.Limits = [500 5000];
            app.ThresholdEditField.Editable = 'off';
            app.ThresholdEditField.Value = 500;
            app.ModelDropDownLabel = uilabel(app.SettingImageTestPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.SettingImageTestPanel);
            app.ModelDropDown.Items = {};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = {};
            app.StatusBarPanel = uipanel(app.FormTest);
            app.StatusBarPanel.Title = 'Status Bar';
            app.StatusLabel = uilabel(app.StatusBarPanel);
            app.StatusLabel.Text = 'Ready';
            app.DetailResultPanel = uipanel(app.FormTest);
            app.DetailResultPanel.Title = 'Detail Result';
            app.NamaLabel = uilabel(app.DetailResultPanel);
            app.NamaLabel.Text = 'Nama';
            app.LabelNamaFile = uilabel(app.DetailResultPanel);
            app.LabelNamaFile.Text = '-';
            app.UkuranLabel = uilabel(app.DetailResultPanel);
            app.UkuranLabel.Text = 'Ukuran';
            app.LabelUkuran = uilabel(app.DetailResultPanel);
            app.LabelUkuran.Text = 'width x height';
            app.WaktuProsesLabel = uilabel(app.DetailResultPanel);
            app.WaktuProsesLabel.Text = 'Waktu Proses';
            app.LabelExcTime = uilabel(app.DetailResultPanel);
            app.LabelExcTime.Text = '0 ms';
            app.NilaiEuclideanLabel = uilabel(app.DetailResultPanel);
            app.NilaiEuclideanLabel.Text = 'Nilai Euclidean';
            app.LabelMinEuc = uilabel(app.DetailResultPanel);
            app.LabelMinEuc.Text = '0';
            app.KelasLabel = uilabel(app.DetailResultPanel);
            app.KelasLabel.Text = 'Kelas';
            app.LabelKelas = uilabel(app.DetailResultPanel);
            app.LabelKelas.Text = '-';
            app.HasilKlasifikasiEuclideanPanel = uipanel(app.FormTest);
            app.HasilKlasifikasiEuclideanPanel.Title = 'Hasil Klasifikasi Euclidean';
            app.UITableEuclidean = uitable(app.HasilKlasifikasiEuclideanPanel);
            app.UITableEuclidean.ColumnName = {'No'; 'Data'; 'Nilai Euclidean'};
            app.UITableEuclidean.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = TestingForm

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FormTest)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FormTest)
        end
    end
end