classdef TrainingForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FormTraining                 matlab.ui.Figure
        ListDataTrainingPanel        matlab.ui.container.Panel
        UITable                      matlab.ui.control.Table
        LokasiFolderEditFieldLabel   matlab.ui.control.Label
        LokasiFolderEditField        matlab.ui.control.EditField
        PilihButton                  matlab.ui.control.Button
        ProsesTrainingDataButton     matlab.ui.control.Button
        ResizeImageLabel             matlab.ui.control.Label
        ResizeImageEditField         matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel      matlab.ui.control.Label
        ThresholdEditField           matlab.ui.control.NumericEditField
        range5005000Label            matlab.ui.control.Label
        DetailDataTrainPanel         matlab.ui.container.Panel
        ClassLabel                   matlab.ui.control.Label
        LabelClassJumlah             matlab.ui.control.Label
        DataLabel                    matlab.ui.control.Label
        LabelDataJumlah              matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        LabelClassLabel              matlab.ui.control.Label
        HasilKlasifikasiPanel        matlab.ui.container.Panel
        TabelHasilKlasifikasiPanel   matlab.ui.container.Panel
        UITableClassification        matlab.ui.control.Table
        ConfusionMatrixPanel         matlab.ui.container.Panel
        DetailKlasifikasiPanel       matlab.ui.container.Panel
        AkurasiLabel                 matlab.ui.control.Label
        SensitivityLabel             matlab.ui.control.Label
        SpecificityLabel             matlab.ui.control.Label
        PrecisionLabel               matlab.ui.control.Label
        F1_scoreLabel                matlab.ui.control.Label
        LabelAkurasi                 matlab.ui.control.Label
        Label_Sensitif               matlab.ui.control.Label
        Label_Spec                   matlab.ui.control.Label
        Label_Prec                   matlab.ui.control.Label
        Label_F1                     matlab.ui.control.Label
        ModelTrainPanel              matlab.ui.container.Panel
        FilenameModelEditFieldLabel  matlab.ui.control.Label
        FilenameModelEditField       matlab.ui.control.EditField
        SaveButton                   matlab.ui.control.Button
        StatusBarPanel               matlab.ui.container.Panel
        StatusLabel                  matlab.ui.control.Label
    end

    
    properties (Access = public)
        FaceDetector
        DRMFModel 
        ReduceTrainData
        
        PCAVar
        ProjectedImages
        Eigenfaces
        resultClass
        ResultAcc 
        LabelNumber
        
        exTime
    end 
    
    methods (Access = public)
        
        function [ProjectedImages, resultClass, ResultAcc] = RecognitionProcess(app, Eigenfaces,A,m)
            Data = app.UITable.Data;
            
            Train_Number = size(A,2);
            app.ProjectedImages = zeros(11,Train_Number);
            
            fprintf("Collecting data Projection of centered images for classification using Euclidian Distance \n");
            
            app.StatusLabel.Text = "Process Projection of centered images ...."; pause(0.00000000000000001);
            for i = 1 : Train_Number
                temp = Eigenfaces' * A(:,i); % Projection of centered images into facespace
                ProjectedImages(:,i) = temp; 
            end
            
            fprintf("Projected Image of each data train : \n");
            ProjectedImages
            
            app.StatusLabel.Text = "Prepare to classification using euclidean distance...."; pause(0.00000000000000001);
            Training_Path = app.LokasiFolderEditField.Value;
            
            flist = dir(fullfile(Training_Path,'*.jpeg'));
            totalData = size(flist,1); 
            resultClass = zeros(totalData,2);
            LabelResult = cell(totalData,4);
             
            fprintf("==================Start Classification===================== \n");
            for s = 1:totalData
                app.StatusLabel.Text = sprintf("Processing to classification %s ....",flist(s).name); pause(0.00000000000000001);
                
                fprintf("============================Classify process image : %s ====================\n", flist(s).name); 
                
                im = imread(fullfile(Training_Path,flist(s).name));
                temp = FaceDetection(im,app.FaceDetector, app.DRMFModel,app.ResizeImageEditField.Value);
                
                [irow, icol] = size(temp);
                InImage = reshape(temp',irow*icol,1); 
                Difference = double(InImage)-m; 
                Projected_TestImage = Eigenfaces'*Difference; 
                
                fprintf("Convert 1D matrix : (See var InImage in workspace)\n");
                assignin('base','InImage', InImage);
                
                fprintf("Difference InImage with PCA Mean : (See var Difference in workspace)\n");
                assignin('base','Difference', Difference);
                
                fprintf("Projected Test Image : (See var Projected_TestImage in workspace)\n");
                assignin('base','Projected_TestImage', Projected_TestImage);
                                  
                Euc_dist = zeros(1,totalData) ;
                for i = 1 : Train_Number
                    q = ProjectedImages(:,i);
                    temp = ( norm( Projected_TestImage - q ) )^2;
                    Euc_dist(:,i) = temp;
                end
                fprintf("List of value Euclidean Distance : \n");
                Euc_dist
                
                [Euc_dist_min , Recognized_index] = min(Euc_dist);
                
                fprintf("Minimum value from Euc_dist %d \n", Euc_dist_min);
                
                OutputName = Data{Recognized_index,4}; 
                
                fprintf("Data found : %s \n", OutputName);
                
                nameAct = strsplit(flist(s).name,'-');
                namePre = strsplit(OutputName,'-');
                
                LabelResult{s,1} = num2str(s);  
                LabelResult{s,2} = nameAct{1}; %predict
                LabelResult{s,3} = namePre{1}; %actual
                LabelResult{s,4} = num2str(Euc_dist_min);  
                
                LabelResult
                assignin('base','LabelResult',LabelResult);
                assignin('base','LabelNumber',app.LabelNumber);
                
                resultClass(s,1) = find(cellfun(@(k) ~isempty(strfind(LabelResult{s,2}, k)), app.LabelNumber));%predict
                resultClass(s,2) = find(cellfun(@(k) ~isempty(strfind(LabelResult{s,3}, k)), app.LabelNumber));%actual
                
                fprintf("============================End Of Classify process image : %s ====================\n\n", flist(s).name); 
            end
           
            app.UITableClassification.Data = LabelResult;
            
            app.StatusLabel.Text = "Classification is done"; pause(0.00000000000000001);
            
            app.StatusLabel.Text = "Showing visualize Confusion Matrix"; pause(0.00000000000000001);
            confusionchart(app.ConfusionMatrixPanel,LabelResult(:,3),LabelResult(:,2));  
            
            app.StatusLabel.Text = "Show all data detail from classification....."; pause(0.00000000000000001);
             
            [c_matrix,ResultAcc]= confusion.getMatrix(resultClass(:,1),resultClass(:,2));
            
            fprintf("Confusion Matrix: %s \n", c_matrix);
            fprintf("Details of the classification results : \n");
            ResultAcc
            
            app.LabelAkurasi.Text = sprintf("%d%%",(ResultAcc.Accuracy*100));
            app.Label_Sensitif.Text = sprintf("%d%%",(ResultAcc.Sensitivity *100));
            app.Label_Spec.Text = sprintf("%d%%",(ResultAcc.Specificity *100));
            app.Label_Prec.Text = sprintf("%d%%",(ResultAcc.Precision *100));
            app.Label_F1.Text = sprintf("%d%%",(ResultAcc.F1_score *100)); 
            
            ResultAcc.confusionmat = c_matrix;
        end

        
        function visualisasiEigenface(app)
            Num_Eigenvalue = size(app.Eigenfaces,2);
            
            img = zeros((app.ResizeImageEditField.Value), (app.ResizeImageEditField.Value)); 
            
            figure('Name','Eigenface');
            for i=1:min(Num_Eigenvalue,16)
                img(:) = app.Eigenfaces(:,i);
                subplot(4,4,i);
                imshow(img',[]);
            end
            
            saveas(gcf,fullfile('output',[app.FilenameModelEditField.Value '.png']));
        end
        
        function [m, A, Eigenfaces, Eig_vec] = PCAFeatureExt(app, ReduceTrainData) 
            
            app.StatusLabel.Text = "Processing to calculate all data train using PCA"; pause(0.00000000000000001);
            
            fprintf("==================Start PCA Calculation===================== \n");
            
            fprintf("Average of ReduceTrainData : (See var m in workspace....) \n"); 
            m = mean(ReduceTrainData,2);  % average 
            assignin('base','m',m); 
            
            Train_Number = size(ReduceTrainData,2);
            temp_m = zeros(size(ReduceTrainData,1),Train_Number) ;  
            
            for i = 1 : Train_Number
                temp_m(:,i) = m; 
            end
            
            fprintf("Zero Mean : (See var A in workspace....)\n"); 
            A = double(ReduceTrainData) - temp_m; %zero mean
            
            assignin('base','A',A); 
            
            fprintf("Matrix Covarian : (See var L in workspace....)\n");
            L = A'*A;% matrix covarian
            assignin('base','L',L); 

            fprintf("EigenValue : \n");
            [V, D] = eig(L)
            
            Eig_vec = [];
            thresh = app.ThresholdEditField.Value;
            
            fprintf("Threshold : %d\n", thresh);
            
            for i = 1 : size(V,2) 
                if( D(i,i)> thresh ) % Set Threshold value whatever you like
                    Eig_vec = [Eig_vec V(:,i)];
                end
            end
            
            fprintf("EigenVector : \n");
            Eig_vec 
            
            fprintf("Eigenfaces : (See var Eigenfaces in workspace....) \n");
            Eigenfaces = A * Eig_vec;
            assignin('base','Eigenfaces',Eigenfaces);
            
            app.PCAVar.mean         = temp_m;
            app.PCAVar.zeromean     = A;
            app.PCAVar.eigenvalV    = V;
            app.PCAVar.eigenValD    = D;
            app.PCAVar.eigenvect    = Eig_vec;
            app.PCAVar.eigenface    = Eigenfaces;
            
            fprintf("==================END OF PCA Calculation===================== \n");
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('class\'));
            clc;
            movegui(app.FormTraining,'center');
            
            fitting_model     ='models/Chehra_f1.0.mat'; % Load Models
            app.DRMFModel     = load(fitting_model);  
            
            app.FaceDetector  = vision.CascadeObjectDetector(); % Face Detector
            app.FaceDetector.MinSize = [60 60];
            app.FaceDetector.MergeThreshold = 3;
            
            fprintf("============Training Form==================\n");
            fprintf("Face Detection using DRMF and CascadeObjectDetector \n");
            fprintf("Setting CascadeObjectDetector : \n \tMinSize : [60 60] \n \tMergeThreshold : 3 \n");
            
            app.StatusLabel.Text = "Ready";
            

        end

        % Button pushed function: PilihButton
        function PilihButtonPushed(app, event)
            selpath = uigetdir(fullfile(pwd,'dataset/Train')); 
            
            if isequal(selpath,0)
                msgbox("Lokasi kosong, harap pilih kembali!",'modal');
                pause(1.5)
                selpath = uigetdir(fullfile(pwd,'dataset/Train')); 
            end
            
            app.LokasiFolderEditField.Value = selpath;
            
            fprintf("Lokasi Training Data : %s\n", app.LokasiFolderEditField.Value);
            
            app.StatusLabel.Text = "Processing load data train"; pause(0.00000000000000001);
            
            dname       = fullfile(app.LokasiFolderEditField.Value);
            FileTrain   = dir(fullfile(dname,'*.jpeg'));
            countData   = size(FileTrain,1);
            
            fprintf("Jumlah Data Training : %s\n", num2str(countData));
            
            dataTrain = cell(countData, 5);
           
            for i = 1 : size(FileTrain,1) 
                nameSplit = strsplit(FileTrain(i).name,'-');
                
                dataTrain{i, 1} = num2str(i);
                dataTrain{i, 2} = nameSplit{1};
                dataTrain{i, 3} = fullfile(FileTrain(i).folder,FileTrain(i).name) ;
                dataTrain{i, 4} = FileTrain(i).name;
                dataTrain{i, 5} = nameSplit{2};
            end
                         
            fprintf("List Data Training :"); 
            dataTrain
            
            unq = unique(dataTrain(:, 2));
            app.LabelNumber = unq;
            assignin('base','unq', unq);
            app.LabelClassJumlah.Text = num2str(size(unq,1));
            app.LabelDataJumlah.Text = num2str(i);
            app.LabelClassLabel.Text = strjoin(unq,",");
            
            fprintf("Jumlah Class : %s\n List Class : %s \n", size(unq,2), app.LabelClassLabel.Text);
            
            app.UITable.Data = dataTrain;
            app.StatusLabel.Text = "Data train is loaded"; pause(0.00000000000000001);
        end

        % Button pushed function: ProsesTrainingDataButton
        function ProsesTrainingDataButtonPushed(app, event)
            tic;
            if(size(app.UITable.Data,1) < 1 )
                msgbox('Data Train Kosong ! Silahkan pilih data untuk Proses Training');
                app.StatusLabel.Text = "Error! Data Training Kosong"; pause(0.00000000000000001);
                return
            end
            
            app.StatusLabel.Text = "Preparing system to pre-processing data"; pause(0.00000000000000001);
              
            resizeS = app.ResizeImageEditField.Value;
            data = app.UITable.Data; 
            app.ReduceTrainData = zeros(resizeS*resizeS, size(data,1));
            
            assignin('base','TrainData',app.UITable.Data);
            
            fprintf("Prepare Setting PCA : \n Size of 1D image : %dx%d \n", size(app.ReduceTrainData,1), size(app.ReduceTrainData,2)); 
            
            for i = 1 : size(data,1)
                 img = imread(data{i,3}); 
                 
                 imgFace = FaceDetection(img,app.FaceDetector,app.DRMFModel,resizeS);
                 [irow, icol] = size(imgFace);
                 temp = reshape(imgFace',irow*icol,1);   % Reshaping 2D images to 1D image vectors
         
                 app.ReduceTrainData(:, i) = temp; 
                 app.StatusLabel.Text = sprintf("Getting facial area %s process....",data{i,3}); pause(0.00000000000000001);
            end
            
            fprintf("1D data Images : (See var ReduceTrainData in workspace....) \n"); 
            assignin('base','ReduceTrainData',app.ReduceTrainData);
            
            app.StatusLabel.Text = "All facial area is saved"; pause(0.00000000000000001); 
            
            % PCA Calculation    
            app.StatusLabel.Text = "Preparing collect all data to Feature Extraction using PCA"; pause(0.7);
                        
            [m, A, app.Eigenfaces] = PCAFeatureExt(app, app.ReduceTrainData);
            
            app.StatusLabel.Text = "Calculation is complete, data has been saved"; pause(0.00000000000000001);
            
            app.StatusLabel.Text = "Collecting data for classification..."; pause(0.00000000000000001);
            [app.ProjectedImages, app.resultClass, app.ResultAcc] = RecognitionProcess(app, app.Eigenfaces,A,m);
            
            app.StatusLabel.Text = "Show visualize Eigenface from train data"; pause(0.00000000000000001);
            
            app.FilenameModelEditField.Value = sprintf("Model-%s", datestr(now,'dd-mm-yy_HHMMSS'));
            visualisasiEigenface(app);
            
            app.exTime = toc;
            app.StatusLabel.Text = sprintf("All process is done with excecution time : %f", app.exTime); pause(0.00000000000000001);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            model.datatrain = app.UITable.Data;
            model.resize = app.ResizeImageEditField.Value;
            model.thresholdPCA = app.ThresholdEditField.Value;
            model.sumOfData = app.LabelDataJumlah.Text;
            model.sumOfClass = app.LabelClassJumlah.Text;
            model.PCA = app.PCAVar;
            model.ProjectedImages = app.ProjectedImages;
            
            model.tblresult = app.UITableClassification.Data;
            model.resultClass = app.resultClass;
            model.classification = app.ResultAcc;            
            model.executiontime = app.exTime;
            
            save(fullfile('model',[app.FilenameModelEditField.Value '.mat']),'model');
            
            app.StatusLabel.Text = sprintf("Model successfully saved: %s", fullfile('model',[app.FilenameModelEditField.Value '.mat'])); 
            pause(0.00000000000000001);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FormTraining = uifigure;
            app.FormTraining.Name = 'Form Training';
            app.ListDataTrainingPanel = uipanel(app.FormTraining);
            app.ListDataTrainingPanel.Title = 'List Data Training';
            app.UITable = uitable(app.ListDataTrainingPanel);
            app.UITable.ColumnName = {'No'; 'Nama'; 'Lokasi'; 'Filename'; 'Kelas'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = false;
            app.LokasiFolderEditFieldLabel = uilabel(app.ListDataTrainingPanel);
            app.LokasiFolderEditFieldLabel.Text = {'Lokasi'; 'Folder'};
            app.LokasiFolderEditField = uieditfield(app.ListDataTrainingPanel, 'text');
            app.LokasiFolderEditField.Editable = 'off';
            app.PilihButton = uibutton(app.ListDataTrainingPanel, 'push');
            app.PilihButton.ButtonPushedFcn = createCallbackFcn(app, @PilihButtonPushed, true);
            app.PilihButton.Text = 'Pilih';
            app.ProsesTrainingDataButton = uibutton(app.ListDataTrainingPanel, 'push');
            app.ProsesTrainingDataButton.ButtonPushedFcn = createCallbackFcn(app, @ProsesTrainingDataButtonPushed, true);
            app.ProsesTrainingDataButton.Text = 'Proses Training Data';
            app.ResizeImageLabel = uilabel(app.ListDataTrainingPanel);
            app.ResizeImageLabel.Text = {'Resize '; 'Image'};
            app.ResizeImageEditField = uieditfield(app.ListDataTrainingPanel, 'numeric');
            app.ResizeImageEditField.Value = 50;
            app.ThresholdEditFieldLabel = uilabel(app.ListDataTrainingPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.ListDataTrainingPanel, 'numeric');
            app.ThresholdEditField.Limits = [500 5000];
            app.ThresholdEditField.Value = 1000;
            app.range5005000Label = uilabel(app.ListDataTrainingPanel);
            app.range5005000Label.Text = 'range : 500 - 5000';
            app.DetailDataTrainPanel = uipanel(app.ListDataTrainingPanel);
            app.DetailDataTrainPanel.Title = 'Detail Data Train';
            app.ClassLabel = uilabel(app.DetailDataTrainPanel);
            app.ClassLabel.Text = 'Class  : ';
            app.LabelClassJumlah = uilabel(app.DetailDataTrainPanel);
            app.LabelClassJumlah.Text = '';
            app.DataLabel = uilabel(app.DetailDataTrainPanel);
            app.DataLabel.Text = 'Data   : ';
            app.LabelDataJumlah = uilabel(app.DetailDataTrainPanel);
            app.LabelDataJumlah.Text = '';
            app.Label = uilabel(app.DetailDataTrainPanel);
            app.Label.Text = 'Label : ';
            app.LabelClassLabel = uilabel(app.DetailDataTrainPanel);
            app.LabelClassLabel.Text = '';
            app.HasilKlasifikasiPanel = uipanel(app.FormTraining);
            app.HasilKlasifikasiPanel.Title = 'Hasil Klasifikasi';
            app.TabelHasilKlasifikasiPanel = uipanel(app.HasilKlasifikasiPanel);
            app.TabelHasilKlasifikasiPanel.Title = 'Tabel Hasil Klasifikasi';
            app.UITableClassification = uitable(app.TabelHasilKlasifikasiPanel);
            app.UITableClassification.ColumnName = {'No'; 'Aktual Data'; 'Prediksi Data'; 'Distance Min'};
            app.UITableClassification.RowName = {};
            app.ConfusionMatrixPanel = uipanel(app.HasilKlasifikasiPanel);
            app.ConfusionMatrixPanel.Title = 'Confusion Matrix';
            app.DetailKlasifikasiPanel = uipanel(app.HasilKlasifikasiPanel);
            app.DetailKlasifikasiPanel.Title = 'Detail Klasifikasi';
            app.AkurasiLabel = uilabel(app.DetailKlasifikasiPanel);
            app.AkurasiLabel.Text = 'Akurasi        :';
            app.SensitivityLabel = uilabel(app.DetailKlasifikasiPanel);
            app.SensitivityLabel.Text = 'Sensitivity   :';
            app.SpecificityLabel = uilabel(app.DetailKlasifikasiPanel);
            app.SpecificityLabel.Text = 'Specificity   :';
            app.PrecisionLabel = uilabel(app.DetailKlasifikasiPanel);
            app.PrecisionLabel.Text = 'Precision     : ';
            app.F1_scoreLabel = uilabel(app.DetailKlasifikasiPanel);
            app.F1_scoreLabel.Text = 'F1_score     : ';
            app.LabelAkurasi = uilabel(app.DetailKlasifikasiPanel);
            app.LabelAkurasi.Text = '0';
            app.Label_Sensitif = uilabel(app.DetailKlasifikasiPanel);
            app.Label_Sensitif.Text = '0';
            app.Label_Spec = uilabel(app.DetailKlasifikasiPanel);
            app.Label_Spec.Text = '0';
            app.Label_Prec = uilabel(app.DetailKlasifikasiPanel);
            app.Label_Prec.Text = '0';
            app.Label_F1 = uilabel(app.DetailKlasifikasiPanel);
            app.Label_F1.Text = '0';
            app.ModelTrainPanel = uipanel(app.HasilKlasifikasiPanel);
            app.ModelTrainPanel.Title = 'Model Train';
            app.FilenameModelEditFieldLabel = uilabel(app.ModelTrainPanel);
            app.FilenameModelEditFieldLabel.Text = 'Filename Model';
            app.FilenameModelEditField = uieditfield(app.ModelTrainPanel, 'text');
            app.SaveButton = uibutton(app.ModelTrainPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.StatusBarPanel = uipanel(app.FormTraining);
            app.StatusBarPanel.Title = 'Status Bar';
            app.StatusLabel = uilabel(app.StatusBarPanel);
            app.StatusLabel.Text = 'Ready';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TrainingForm

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FormTraining)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FormTraining)
        end
    end
end