classdef mainForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFormUIFigure    matlab.ui.Figure
        TrainingDataButton  matlab.ui.control.Button
        TestDataButton      matlab.ui.control.Button
        PengenalanWajahSiswaTKAlIstiqomahLabel  matlab.ui.control.Label
        LabelProcess        matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.MainFormUIFigure,'center')
        end

        % Button pushed function: TrainingDataButton
        function TrainingDataButtonPushed(app, event)
            app.LabelProcess.Text = "Processing to open Form Training ....."; pause(0.00000000000001);
            formass = 'train'; 
            assignin('base','formass',formass);
            appCall 
            
            app.LabelProcess.Text = "Form Training is Open"; pause(0.00000000000001);
        end

        % Button pushed function: TestDataButton
        function TestDataButtonPushed(app, event)
            app.LabelProcess.Text = "Processing to open Form Testing ....."; pause(0.00000000000001);
            formass = 'test'; 
            assignin('base','formass',formass);
            appCall 
            
            app.LabelProcess.Text = "Form Testing is Open"; pause(0.00000000000001);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFormUIFigure = uifigure;
            app.MainFormUIFigure.Name = 'Main Form';
            app.TrainingDataButton = uibutton(app.MainFormUIFigure, 'push');
            app.TrainingDataButton.ButtonPushedFcn = createCallbackFcn(app, @TrainingDataButtonPushed, true);
            app.TrainingDataButton.Text = 'Training Data';
            app.TestDataButton = uibutton(app.MainFormUIFigure, 'push');
            app.TestDataButton.ButtonPushedFcn = createCallbackFcn(app, @TestDataButtonPushed, true);
            app.TestDataButton.Text = 'Test Data';
            app.PengenalanWajahSiswaTKAlIstiqomahLabel = uilabel(app.MainFormUIFigure);
            app.PengenalanWajahSiswaTKAlIstiqomahLabel.Text = {'Pengenalan Wajah Siswa'; 'TK Al-Istiqomah'};
            app.LabelProcess = uilabel(app.MainFormUIFigure);
            app.LabelProcess.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mainForm

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFormUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFormUIFigure)
        end
    end
end